classdef Pneumatics_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PneumaticsProgramLabel  matlab.ui.control.Label
        NeutralButton           matlab.ui.control.Button
        PositiveButton          matlab.ui.control.Button
        NegativeButton          matlab.ui.control.Button
        SetpointButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        pneumatics =serial('COM5',"Baudrate",19200);
        

    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
    fopen(app.pneumatics);        


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
           delete(instrfind);
        delete(app)
            
        end

        % Button pushed function: PositiveButton
        function PositiveButtonPushed(app, event)
       
            fprintf(app.pneumatics,'<VI,0,0>')
            fprintf(app.pneumatics,'<VI,1,0>')
            fprintf(app.pneumatics,'<VI,2,0>')
            
            fprintf(app.pneumatics,'<VI,2,1>')
            fprintf(app.pneumatics,'<VO,0,1>') 
        end

        % Button pushed function: NegativeButton
        function NegativeButtonPushed(app, event)
            
            fprintf(app.pneumatics,'<VI,0,0>')
            fprintf(app.pneumatics,'<VI,1,0>')
            fprintf(app.pneumatics,'<VI,2,0>')
            
            fprintf(app.pneumatics,'<VI,0,1>')
            fprintf(app.pneumatics,'<VO,0,1>') 
        end

        % Button pushed function: SetpointButton
        function SetpointButtonPushed(app, event)
            fprintf(app.pneumatics,'<PSP,10> \n')
            fprintf(app.pneumatics,'<NSP,-10> \n')
        end

        % Button pushed function: NeutralButton
        function NeutralButtonPushed(app, event)
            
            fprintf(app.pneumatics,'<VI,0,0>')
            fprintf(app.pneumatics,'<VI,1,0>')
            fprintf(app.pneumatics,'<VI,2,0>')
            
            fprintf(app.pneumatics,'<VI,1,1>')
            fprintf(app.pneumatics,'<VO,0,1>') 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PneumaticsProgramLabel = uilabel(app.UIFigure);
            app.PneumaticsProgramLabel.Text = 'Pneumatics Program';
            app.PositiveButton = uibutton(app.UIFigure, 'push');
            app.PositiveButton.ButtonPushedFcn = createCallbackFcn(app, @PositiveButtonPushed, true);
            app.PositiveButton.Text = 'Positive';
            app.NegativeButton = uibutton(app.UIFigure, 'push');
            app.NegativeButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeButtonPushed, true);
            app.NegativeButton.Text = 'Negative';
            app.SetpointButton = uibutton(app.UIFigure, 'push');
            app.SetpointButton.ButtonPushedFcn = createCallbackFcn(app, @SetpointButtonPushed, true);
            app.SetpointButton.Text = 'Setpoint';
            app.NeutralButton = uibutton(app.UIFigure, 'push');
            app.NeutralButton.ButtonPushedFcn = createCallbackFcn(app, @NeutralButtonPushed, true);
            app.NeutralButton.Text = 'Neutral';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pneumatics_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end