classdef adhesion_tester_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        MoveINButton                   matlab.ui.control.Button
        MoveOUTButton                  matlab.ui.control.Button
        PreloadNEditFieldLabel         matlab.ui.control.Label
        PreloadNEditField              matlab.ui.control.NumericEditField
        TestButton                     matlab.ui.control.Button
        StepMotorControlLabel          matlab.ui.control.Label
        ForceGaugeConnectionLampLabel  matlab.ui.control.Label
        ForceGaugeConnectionLamp       matlab.ui.control.Lamp
        StepMotorConnectionLampLabel   matlab.ui.control.Label
        StepMotorConnectionLamp        matlab.ui.control.Lamp
        ReconnectButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ForceGaugeConnectionLamp.Color=[1,1,0];
            app.StepMotorConnectionLamp.Color=[1,1,0];
            
            try
                [force_gauge,step_motor] = connect_instruments();
                
                if strcmp(force_gauge.Status,'open')
                    app.ForceGaugeConnectionLamp.Color=[0,1,0];
                end
                
                if strcmp(step_motor.Status,'open')
                    app.ForceGaugeConnectionLamp.Color=[0,1,0];
                end
                
                assignin('base','force_gauge',force_gauge)
                assignin('base','step_motor',step_motor)
            catch
                app.ForceGaugeConnectionLamp.Color=[1,0,0];
                app.StepMotorConnectionLamp.Color=[1,0,0];
            end 
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            disp(['testing at ',num2str(app.PreloadNEditField.Value)]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MoveINButton = uibutton(app.LeftPanel, 'push');
            app.MoveINButton.Text = 'Move IN';
            app.MoveOUTButton = uibutton(app.LeftPanel, 'push');
            app.MoveOUTButton.Text = 'Move OUT';
            app.PreloadNEditFieldLabel = uilabel(app.LeftPanel);
            app.PreloadNEditFieldLabel.Text = 'Preload (N)';
            app.PreloadNEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TestButton = uibutton(app.LeftPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.StepMotorControlLabel = uilabel(app.LeftPanel);
            app.StepMotorControlLabel.Text = 'Step Motor Control';
            app.ForceGaugeConnectionLampLabel = uilabel(app.LeftPanel);
            app.ForceGaugeConnectionLampLabel.Text = 'Force Gauge Connection';
            app.ForceGaugeConnectionLamp = uilamp(app.LeftPanel);
            app.StepMotorConnectionLampLabel = uilabel(app.LeftPanel);
            app.StepMotorConnectionLampLabel.Text = 'Step Motor Connection';
            app.StepMotorConnectionLamp = uilamp(app.LeftPanel);
            app.ReconnectButton = uibutton(app.LeftPanel, 'push');
            app.ReconnectButton.Text = 'Re-connect';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Force Output')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Force')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adhesion_tester_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end